classdef admin_page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        scheduleTime            matlab.ui.control.ListBox
        VIEWCANDIDATESButton    matlab.ui.control.Button
        UPLOADCANDIDATESButton  matlab.ui.control.Button
        scheduled               matlab.ui.control.Label
        SMARTSCHEDULEButton     matlab.ui.control.Button
        ADMINDASHBOARDButton    matlab.ui.control.Button
        table                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        resp; % Description
        candidates;
        unavailable = 0;
        blocked;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStartUp(app)
            app.resp = webread("https://amused-pleat-goat.cyclic.app/interviewer/getAll");
            app.candidates = webread("https://amused-pleat-goat.cyclic.app/candidate/getAll");
            disp(length(app.candidates));

            candidateMap = containers.Map('KeyType', 'char', 'ValueType', 'char');
            if(iscell(app.candidates))
                for i=1:length(app.candidates)
                    % fprintf("%s - %s",app.candidates(i).x_id,app.candidates(i).name);
                    candidateMap(char(app.candidates{i}.x_id)) = char(app.candidates{i}.name);
                    fprintf("%s %s\n",app.candidates{i}.x_id,app.candidates{i}.name);
                end
            else 
                for i=1:length(app.candidates)
                    % fprintf("%s - %s",app.candidates(i).x_id,app.candidates(i).name);
                    candidateMap(char(app.candidates(i).x_id)) = char(app.candidates(i).name);
                    fprintf("%s %s\n",app.candidates(i).x_id,app.candidates(i).name);
                end
            end

            n_rows = length(app.resp);
            n_cols = 9;
            disp(n_rows);
            app.blocked = zeros(n_rows, n_cols);
            app.table.Data = cell(n_rows,n_cols);
            

            for i = 1:length(app.resp)
                person = app.resp(i);
                for j = 1:length(person.interviewSlots)
                    slot = person.interviewSlots(j);
                    timeSlot = slot.timeSlot;
                    try
                        app.table.Data{i, timeSlot.start-8} = char(candidateMap(slot.candidateId));
                    catch
                        disp(slot.candidateId);
                    end
                    style = uistyle;
                    style.BackgroundColor = [0.18,0.43,0.12];
                    addStyle(app.table,style,"cell",[i, timeSlot.start-8]);

                end

                for j = 1:length(person.blockedSlots)
                    slot = person.blockedSlots(j);
                    style = uistyle;
                    style.BackgroundColor = "red";
                    
                    addStyle(app.table,style,"cell",[i, slot.start-8]);
                    app.blocked(i,slot.start-8) = 1;
                end
            end

            endHour = 18;
            startHour = 9;
            timeStrings = cell(1, endHour - startHour + 1);

            for hour = startHour:endHour
                timeStrings{hour - startHour + 1} = sprintf('%02d:00', hour);
            end
            app.table.ColumnName = timeStrings;

            interviewerNames = cell(1, n_rows);
            for num = 1:n_rows
                interviewer = app.resp(num);
                interviewer_name = sprintf("%s (%s)",interviewer.name,interviewer.type);
                interviewerNames{num} = num2str(interviewer_name);
            end
            disp(interviewerNames);
            app.table.RowName = interviewerNames;
        end

        % Cell selection callback: table
        function tableCellSelection(app, event)
            indices = event.Indices;
            % disp(indices);
            interviewerNumber = indices(1);
            if ~app.blocked(indices(1),indices(2))
                dialogbox(app,app.resp(interviewerNumber),interviewerNumber);
                % disp(indices);
            end
        end

        % Button pushed function: SMARTSCHEDULEButton
        function smartCallback(app, event)
            % API call 
            time = str2double(app.scheduleTime.Value);
            data = struct('currentTime',time);
            webwrite("https://amused-pleat-goat.cyclic.app/interviewer/smartfill",data);
            app.scheduled.Visible = "on";
        end

        % Button pushed function: UPLOADCANDIDATESButton
        function uploadCallback(app, event)
            [file,path] = uigetfile('*.csv');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
            end
            table_ = readtable(string(fullfile(path,file)));
            candidateList = table_.candidate;
            data = struct('name',candidateList);
            webwrite("https://amused-pleat-goat.cyclic.app/candidate/upload",data);
        end

        % Button pushed function: VIEWCANDIDATESButton
        function viewCandidatesCallback(app, event)
            candidatesInfo;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.table = uitable(app.UIFigure);
            app.table.ColumnName = '';
            app.table.RowName = {};
            app.table.CellSelectionCallback = createCallbackFcn(app, @tableCellSelection, true);
            app.ADMINDASHBOARDButton = uibutton(app.UIFigure, 'push');
            app.ADMINDASHBOARDButton.Text = 'ADMIN DASHBOARD';
            app.SMARTSCHEDULEButton = uibutton(app.UIFigure, 'push');
            app.SMARTSCHEDULEButton.ButtonPushedFcn = createCallbackFcn(app, @smartCallback, true);
            app.SMARTSCHEDULEButton.Text = 'SMART SCHEDULE';
            app.scheduled = uilabel(app.UIFigure);
            app.scheduled.Visible = 'off';
            app.scheduled.Text = '✓';
            app.UPLOADCANDIDATESButton = uibutton(app.UIFigure, 'push');
            app.UPLOADCANDIDATESButton.ButtonPushedFcn = createCallbackFcn(app, @uploadCallback, true);
            app.UPLOADCANDIDATESButton.Text = 'UPLOAD CANDIDATES';
            app.VIEWCANDIDATESButton = uibutton(app.UIFigure, 'push');
            app.VIEWCANDIDATESButton.ButtonPushedFcn = createCallbackFcn(app, @viewCandidatesCallback, true);
            app.VIEWCANDIDATESButton.Text = 'VIEW CANDIDATES';
            app.scheduleTime = uilistbox(app.UIFigure);
            app.scheduleTime.Items = {'9', '10', '11', '12', '13', '14', '15', '16', '17'};
            app.scheduleTime.Value = '9';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = admin_page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end