classdef candidatesInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        CANDIDATELISTButton  matlab.ui.control.Button
        candidatesTable      matlab.ui.control.Table
    end

    
    properties (Access = private)
        resp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStartUp(app)
            app.resp = webread("https://amused-pleat-goat.cyclic.app/candidate/getAll");
            % app.candidates = webread("https://amused-pleat-goat.cyclic.app/candidate/getAll");

            n_rows = length(app.resp);
            n_cols = 4;

            colStrings = cell(1, 4);
            colStrings{1} = "TECHNICAL";
            colStrings{2} = "MANAGERIAL";
            colStrings{3} = "HR";
            colStrings{4} = "LAST INTERVIEW";
            app.candidatesTable.ColumnName = colStrings;

            candidateNames = cell(1, n_rows);
            if(iscell(app.resp))
                for num = 1:n_rows
                    candidate = app.resp{num};
                    candidateNames{num} = num2str(candidate.name);
                end
            else
                for num = 1:n_rows
                    candidate = app.resp(num);
                    candidateNames(num) = num2str(candidate.name);
                end
            end
            app.candidatesTable.RowName = candidateNames;

            dat = cell(n_rows,n_cols);

            % Update candidate status
            for i=1:n_rows
                candidate;
                if iscell(app.resp)
                    candidate = app.resp{i};
                else
                    candidate = app.resp(i);
                end
                dat{i, 4} = char(sprintf("%d - %d",candidate.previousInterview,candidate.previousInterview+1));
                styleg = uistyle;
                styleg.BackgroundColor = "green";
                styler = uistyle;
                styler.BackgroundColor = "red";

                if candidate.status.TECH == 1
                    addStyle(app.candidatesTable,styleg,"cell",[i, 1]);
                elseif candidate.status.TECH == 0
                    addStyle(app.candidatesTable,styler,"cell",[i, 1]);
                end
                
                if candidate.status.MANAGER == 1
                    addStyle(app.candidatesTable,styleg,"cell",[i, 2]);
                elseif candidate.status.MANAGER == 0
                    addStyle(app.candidatesTable,styler,"cell",[i, 2]);
                end

                if candidate.status.HR == 1
                    addStyle(app.candidatesTable,styleg,"cell",[i, 3]);
                elseif candidate.status.HR== 0
                    addStyle(app.candidatesTable,styler,"cell",[i, 3]);
                end
            end

            app.candidatesTable.Data = dat;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.candidatesTable = uitable(app.UIFigure);
            app.candidatesTable.ColumnName = {'NAME'; 'TECHNICAL'; 'MANAGERIAL'; 'HR'};
            app.candidatesTable.RowName = {};
            app.CANDIDATELISTButton = uibutton(app.UIFigure, 'push');
            app.CANDIDATELISTButton.Text = 'CANDIDATE LIST';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = candidatesInfo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end