classdef dialogbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        done                    matlab.ui.control.Label
        SCHEDULEButton          matlab.ui.control.Button
        validity_text           matlab.ui.control.Label
        SCHEDULEASLOTLabel      matlab.ui.control.Label
        CANDIDATEDropDown       matlab.ui.control.DropDown
        CANDIDATEDropDownLabel  matlab.ui.control.Label
        VALIDATEButton          matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        end_min                 matlab.ui.control.DropDown
        end_hr                  matlab.ui.control.DropDown
        start_min               matlab.ui.control.DropDown
        start_hr                matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        startHour = 9;
        startMin = 00;
        endHour = 9;
        endMin = 00;
        valid_ = 0;
        scheduled_ = 0;
        callingStart = 0;
        callingEnd = 0;
        callingApp;

        resp;
        candidates;
        interviewerNumber;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, resp, interviewerNumber)
            app.callingApp = caller;
            app.resp = resp;
            api_url = sprintf("https://amused-pleat-goat.cyclic.app/candidate/getAll?type=%s&all=true",resp.type);
            disp(api_url);
            app.candidates = webread(api_url);
            disp(app.candidates);
            app.interviewerNumber = interviewerNumber;
        end

        % Button pushed function: VALIDATEButton
        function validateCallback(app, event)
            app.startHour = str2double(app.start_hr.Value);
            app.endHour = str2double(app.end_hr.Value);
            app.startMin = str2double(app.start_min.Value);
            app.endMin = str2double(app.end_min.Value);

            % disp(app.endHour);
            % disp(app.startHour);

            if app.startHour > app.endHour
                app.valid_ = 0;
            elseif app.startHour == app.endHour
                if app.startMin >= app.endMin
                    app.valid_ = 0;
                else
                    app.valid_ = 1;
                end
            else
                app.valid_ = 1;
            end

            if app.valid_
                app.validity_text.Text = "VALID";
                app.validity_text.FontColor = [0.18,0.43,0.12];
                disp("valid");
            else 
                app.validity_text.Text = "INVALID";
                app.validity_text.FontColor = "r";
                disp("Invalid");
            end
        end

        % Button pushed function: SCHEDULEButton
        function scheduleCallback(app, event)
            if app.valid_ && app.CANDIDATEDropDown.Value ~= "Select" && ~app.scheduled_
                app.scheduled_ = 1;
                app.done.Visible = "on";
                app.done.Text = '✓';
                app.done.FontSize = 20;
                app.done.FontColor = [0.18,0.43,0.12];
                time_slot = struct('start',app.startHour,'end',app.startHour + 1);
                val = app.CANDIDATEDropDown.Value;
                idx = regexp(val, '\d+', 'match');
                idx = str2double(idx{1});

                candidate_id = app.candidates(idx).x_id;
                candidate_name = app.candidates(idx).name;
                data = struct('email',app.resp.email, 'timeSlot', time_slot, 'candidateId', candidate_id);
                webwrite("https://amused-pleat-goat.cyclic.app/interviewer/fixInterview",data);
                disp(app.startHour-8);
                disp(app.interviewerNumber);
                style = uistyle;
                style.BackgroundColor = [0.18,0.43,0.12];
                addStyle(app.callingApp.table,style,"cell",[app.interviewerNumber, app.startHour - 8]);
                app.callingApp.table.Data{app.interviewerNumber, app.startHour-8} = char(candidate_name);
            else
                app.scheduled_ = 0;
                app.done.Visible = "on";
                app.done.Text = 'X';
                app.done.FontSize = 20;
                app.done.FontColor = "r";
            end
        end

        % Drop down opening function: CANDIDATEDropDown
        function CANDIDATEDropDownOpening(app, event)
            dropdownSize = length(app.candidates) + 1;
            dropDownArray = strings(1,dropdownSize);
            dropDownArray(1) = "SELECT";
            for idx = 2:dropdownSize
                dropDownArray(idx) = strcat(app.candidates(idx-1).name, " ", string(idx-1));
            end
            app.CANDIDATEDropDown.Items = dropDownArray;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.start_hr = uidropdown(app.GridLayout);
            app.start_hr.Items = {'9', '10', '11', '12', '13', '14', '15', '16', '17'};
            app.start_hr.Value = '9';
            app.start_min = uidropdown(app.GridLayout);
            app.start_min.Items = {'00', '15', '30', '45'};
            app.start_min.Value = '00';
            app.end_hr = uidropdown(app.GridLayout);
            app.end_hr.Items = {'9', '10', '11', '12', '13', '14', '15', '16', '17'};
            app.end_hr.Value = '9';
            app.end_min = uidropdown(app.GridLayout);
            app.end_min.Items = {'00', '15', '30', '45'};
            app.end_min.Value = '00';
            app.VALIDATEButton = uibutton(app.UIFigure, 'push');
            app.VALIDATEButton.ButtonPushedFcn = createCallbackFcn(app, @validateCallback, true);
            app.VALIDATEButton.Text = 'VALIDATE';
            app.CANDIDATEDropDownLabel = uilabel(app.UIFigure);
            app.CANDIDATEDropDownLabel.Text = 'CANDIDATE';
            app.CANDIDATEDropDown = uidropdown(app.UIFigure);
            app.CANDIDATEDropDown.Items = {''};
            app.CANDIDATEDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CANDIDATEDropDownOpening, true);
            app.CANDIDATEDropDown.Value = '';
            app.SCHEDULEASLOTLabel = uilabel(app.UIFigure);
            app.SCHEDULEASLOTLabel.Text = 'SCHEDULE A SLOT';
            app.validity_text = uilabel(app.UIFigure);
            app.validity_text.Text = '';
            app.SCHEDULEButton = uibutton(app.UIFigure, 'push');
            app.SCHEDULEButton.ButtonPushedFcn = createCallbackFcn(app, @scheduleCallback, true);
            app.SCHEDULEButton.Text = 'SCHEDULE';
            app.done = uilabel(app.UIFigure);
            app.done.Visible = 'off';
            app.done.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialogbox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end